classdef stimulusV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel_7                        matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        EastattndBLabel                matlab.ui.control.Label
        WestattndBLabel                matlab.ui.control.Label
        WestattndBSlider               matlab.ui.control.Slider
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        EastattndBSlider               matlab.ui.control.Slider
        UIAxes_6                       matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        SearchTab                      matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        CyclerPanel                    matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        EndSpinner                     matlab.ui.control.Spinner
        EndSpinnerLabel                matlab.ui.control.Label
        StepSpinner                    matlab.ui.control.Spinner
        StepSpinnerLabel               matlab.ui.control.Label
        StartSpinner                   matlab.ui.control.Spinner
        StartSpinnerLabel              matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        Knob                           matlab.ui.control.DiscreteKnob
        ModulatorPanel                 matlab.ui.container.Panel
        GridLayout10_2                 matlab.ui.container.GridLayout
        EnergyLabel_2                  matlab.ui.control.Label
        FrequencyHzLabel_2             matlab.ui.control.Label
        Spinner_26                     matlab.ui.control.Spinner
        Spinner_25                     matlab.ui.control.Spinner
        CheckBox_13                    matlab.ui.control.CheckBox
        Spinner_24                     matlab.ui.control.Spinner
        Spinner_23                     matlab.ui.control.Spinner
        CheckBox_12                    matlab.ui.control.CheckBox
        Spinner_22                     matlab.ui.control.Spinner
        Spinner_21                     matlab.ui.control.Spinner
        CheckBox_11                    matlab.ui.control.CheckBox
        Spinner_20                     matlab.ui.control.Spinner
        Spinner_19                     matlab.ui.control.Spinner
        CheckBox_10                    matlab.ui.control.CheckBox
        Spinner_18                     matlab.ui.control.Spinner
        Spinner_17                     matlab.ui.control.Spinner
        CheckBox_9                     matlab.ui.control.CheckBox
        Spinner_16                     matlab.ui.control.Spinner
        Spinner_15                     matlab.ui.control.Spinner
        CheckBox_8                     matlab.ui.control.CheckBox
        CarrierPanel                   matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        EnergyLabel                    matlab.ui.control.Label
        FrequencyHzLabel               matlab.ui.control.Label
        Spinner_14                     matlab.ui.control.Spinner
        Spinner_13                     matlab.ui.control.Spinner
        CheckBox_7                     matlab.ui.control.CheckBox
        Spinner_12                     matlab.ui.control.Spinner
        Spinner_11                     matlab.ui.control.Spinner
        CheckBox_6                     matlab.ui.control.CheckBox
        Spinner_10                     matlab.ui.control.Spinner
        Spinner_9                      matlab.ui.control.Spinner
        CheckBox_5                     matlab.ui.control.CheckBox
        Spinner_8                      matlab.ui.control.Spinner
        Spinner_7                      matlab.ui.control.Spinner
        CheckBox_4                     matlab.ui.control.CheckBox
        Spinner_6                      matlab.ui.control.Spinner
        Spinner_5                      matlab.ui.control.Spinner
        CheckBox_3                     matlab.ui.control.CheckBox
        Spinner_4                      matlab.ui.control.Spinner
        Spinner_3                      matlab.ui.control.Spinner
        CheckBox_2                     matlab.ui.control.CheckBox
        Tab2                           matlab.ui.container.Tab
        SystemPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_3                        matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        Species                        matlab.ui.control.DropDown
        SpeciesDropDownLabel           matlab.ui.control.Label
        TDTStatusPanel                 matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        LoadParamsLamp                 matlab.ui.control.Lamp
        LoadParamsLampLabel            matlab.ui.control.Label
        LoadCircuitLamp                matlab.ui.control.Lamp
        LoadCircuitLampLabel           matlab.ui.control.Label
        ConnectionLamp                 matlab.ui.control.Lamp
        ConnectionLampLabel            matlab.ui.control.Label
        MasterSwitch                   matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        InterStimulusIntervalSlider    matlab.ui.control.Slider
        StimulusDurationmsecEditField  matlab.ui.control.NumericEditField
        StimulusDurationmsecEditFieldLabel  matlab.ui.control.Label
        InterStimulusIntervalsecLabel  matlab.ui.control.Label
        InterStimulusIntervalEditBox   matlab.ui.control.NumericEditField
        Panel_5                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        InterSpeakerIntervalSlider     matlab.ui.control.Slider
        Speaker                        matlab.ui.control.DiscreteKnob
        InterSpeakerIntervalmsecLabel  matlab.ui.control.Label
        InterSpeakerIntervalEditBox    matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MasterState % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InterSpeakerIntervalEditBox.Limits = app.InterSpeakerIntervalSlider.Limits;
            app.InterStimulusIntervalEditBox.Limits = app.InterStimulusIntervalSlider.Limits;
            app.MasterState = 1;
        end

        % Value changed function: InterStimulusIntervalSlider
        function InterStimulusIntervalSliderValueChanged(app, event)
            app.InterStimulusIntervalEditBox.Value = app.InterStimulusIntervalSlider.Value;
        end

        % Value changed function: InterStimulusIntervalEditBox
        function InterStimulusIntervalEditBoxValueChanged(app, event)
            app.InterStimulusIntervalSlider.Value = app.InterStimulusIntervalEditBox.Value;
        end

        % Value changed function: InterSpeakerIntervalSlider
        function InterSpeakerIntervalSliderValueChanged(app, event)
            app.InterSpeakerIntervalEditBox.Value = app.InterSpeakerIntervalSlider.Value;
        end

        % Value changed function: InterSpeakerIntervalEditBox
        function InterSpeakerIntervalEditBoxValueChanged(app, event)
            app.InterSpeakerIntervalSlider.Value = app.InterSpeakerIntervalEditBox.Value;
        end

        % Button pushed function: MasterSwitch
        function MasterSwitchPushed(app, event)
            if app.MasterState == 1
                app.MasterState = 0;
                app.MasterSwitch.Icon = "./icons/mute.svg";
                app.MasterSwitch.BackgroundColor = [1.00,0.00,0.00];
            elseif app.MasterState == 0
                app.MasterState = 1;
                app.MasterSwitch.Icon = "./icons/unmute.svg";
                app.MasterSwitch.BackgroundColor = [0.00,1.00,0.00];
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SystemPanel = uipanel(app.GridLayout);
            app.SystemPanel.Title = 'System';
            app.GridLayout2 = uigridlayout(app.SystemPanel);
            app.Panel_5 = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.Panel_5);
            app.InterSpeakerIntervalEditBox = uieditfield(app.GridLayout4, 'numeric');
            app.InterSpeakerIntervalEditBox.ValueChangedFcn = createCallbackFcn(app, @InterSpeakerIntervalEditBoxValueChanged, true);
            app.InterSpeakerIntervalEditBox.Value = 5;
            app.InterSpeakerIntervalmsecLabel = uilabel(app.GridLayout4);
            app.InterSpeakerIntervalmsecLabel.Text = 'Inter-Speaker Interval (msec)';
            app.Speaker = uiknob(app.GridLayout4, 'discrete');
            app.Speaker.Items = {'West', 'Biaural', 'East'};
            app.Speaker.Value = 'West';
            app.Panel = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.InterSpeakerIntervalSlider = uislider(app.GridLayout5);
            app.InterSpeakerIntervalSlider.Limits = [-40 40];
            app.InterSpeakerIntervalSlider.MajorTicks = [-40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40];
            app.InterSpeakerIntervalSlider.ValueChangedFcn = createCallbackFcn(app, @InterSpeakerIntervalSliderValueChanged, true);
            app.InterSpeakerIntervalSlider.MinorTicks = [-40 -39 -38 -37 -36 -35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.InterSpeakerIntervalSlider.Value = 5;
            app.Panel_4 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.InterStimulusIntervalEditBox = uieditfield(app.GridLayout3, 'numeric');
            app.InterStimulusIntervalEditBox.ValueChangedFcn = createCallbackFcn(app, @InterStimulusIntervalEditBoxValueChanged, true);
            app.InterStimulusIntervalEditBox.Value = 1;
            app.InterStimulusIntervalsecLabel = uilabel(app.GridLayout3);
            app.InterStimulusIntervalsecLabel.Text = 'Inter-Stimulus Interval (sec)';
            app.StimulusDurationmsecEditFieldLabel = uilabel(app.GridLayout3);
            app.StimulusDurationmsecEditFieldLabel.Text = 'Stimulus Duration (msec)';
            app.StimulusDurationmsecEditField = uieditfield(app.GridLayout3, 'numeric');
            app.StimulusDurationmsecEditField.Value = 400;
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.Panel_2);
            app.InterStimulusIntervalSlider = uislider(app.GridLayout6);
            app.InterStimulusIntervalSlider.Limits = [0.5 6.5];
            app.InterStimulusIntervalSlider.MajorTicks = [0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5];
            app.InterStimulusIntervalSlider.ValueChangedFcn = createCallbackFcn(app, @InterStimulusIntervalSliderValueChanged, true);
            app.InterStimulusIntervalSlider.MinorTicks = [];
            app.InterStimulusIntervalSlider.Value = 1;
            app.MasterSwitch = uibutton(app.GridLayout2, 'push');
            app.MasterSwitch.ButtonPushedFcn = createCallbackFcn(app, @MasterSwitchPushed, true);
            app.MasterSwitch.Icon = fullfile(pathToMLAPP, '.', 'icons', 'unmute.svg');
            app.MasterSwitch.Text = '';
            app.TDTStatusPanel = uipanel(app.GridLayout2);
            app.TDTStatusPanel.Title = 'TDT Status';
            app.GridLayout7 = uigridlayout(app.TDTStatusPanel);
            app.ConnectionLampLabel = uilabel(app.GridLayout7);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.GridLayout7);
            app.LoadCircuitLampLabel = uilabel(app.GridLayout7);
            app.LoadCircuitLampLabel.Text = 'Load Circuit';
            app.LoadCircuitLamp = uilamp(app.GridLayout7);
            app.LoadParamsLampLabel = uilabel(app.GridLayout7);
            app.LoadParamsLampLabel.Text = 'Load Params';
            app.LoadParamsLamp = uilamp(app.GridLayout7);
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout8 = uigridlayout(app.Panel_3);
            app.SpeciesDropDownLabel = uilabel(app.GridLayout8);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.Species = uidropdown(app.GridLayout8);
            app.Species.Items = {'Rana pipiens', 'Hyla chrysoceles', 'Hyla versicolor', 'Psudecris feriarum'};
            app.Species.Value = 'Rana pipiens';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.GridLayout9 = uigridlayout(app.SearchTab);
            app.CarrierPanel = uipanel(app.GridLayout9);
            app.CarrierPanel.Title = 'Carrier';
            app.GridLayout10 = uigridlayout(app.CarrierPanel);
            app.CheckBox_2 = uicheckbox(app.GridLayout10);
            app.CheckBox_2.Text = '';
            app.Spinner_3 = uispinner(app.GridLayout10);
            app.Spinner_3.Step = 20;
            app.Spinner_3.LowerLimitInclusive = 'off';
            app.Spinner_3.Limits = [0 5000];
            app.Spinner_3.Value = 150;
            app.Spinner_4 = uispinner(app.GridLayout10);
            app.Spinner_4.Step = 0.25;
            app.Spinner_4.Limits = [0 5];
            app.Spinner_4.Value = 1;
            app.CheckBox_3 = uicheckbox(app.GridLayout10);
            app.CheckBox_3.Text = '';
            app.Spinner_5 = uispinner(app.GridLayout10);
            app.Spinner_5.Step = 20;
            app.Spinner_5.LowerLimitInclusive = 'off';
            app.Spinner_5.Limits = [0 5000];
            app.Spinner_5.Value = 300;
            app.Spinner_6 = uispinner(app.GridLayout10);
            app.Spinner_6.Step = 0.25;
            app.Spinner_6.Limits = [0 5];
            app.Spinner_6.Value = 1;
            app.CheckBox_4 = uicheckbox(app.GridLayout10);
            app.CheckBox_4.Text = '';
            app.Spinner_7 = uispinner(app.GridLayout10);
            app.Spinner_7.Step = 20;
            app.Spinner_7.LowerLimitInclusive = 'off';
            app.Spinner_7.Limits = [0 5000];
            app.Spinner_7.Value = 600;
            app.Spinner_8 = uispinner(app.GridLayout10);
            app.Spinner_8.Step = 0.25;
            app.Spinner_8.Limits = [0 5];
            app.Spinner_8.Value = 1;
            app.CheckBox_5 = uicheckbox(app.GridLayout10);
            app.CheckBox_5.Text = '';
            app.Spinner_9 = uispinner(app.GridLayout10);
            app.Spinner_9.Step = 20;
            app.Spinner_9.LowerLimitInclusive = 'off';
            app.Spinner_9.Limits = [0 5000];
            app.Spinner_9.Value = 1100;
            app.Spinner_10 = uispinner(app.GridLayout10);
            app.Spinner_10.Step = 0.25;
            app.Spinner_10.Limits = [0 5];
            app.Spinner_10.Value = 1;
            app.CheckBox_6 = uicheckbox(app.GridLayout10);
            app.CheckBox_6.Text = '';
            app.Spinner_11 = uispinner(app.GridLayout10);
            app.Spinner_11.Step = 20;
            app.Spinner_11.LowerLimitInclusive = 'off';
            app.Spinner_11.Limits = [0 5000];
            app.Spinner_11.Value = 1600;
            app.Spinner_12 = uispinner(app.GridLayout10);
            app.Spinner_12.Step = 0.25;
            app.Spinner_12.Limits = [0 5];
            app.Spinner_12.Value = 1;
            app.CheckBox_7 = uicheckbox(app.GridLayout10);
            app.CheckBox_7.Text = '';
            app.Spinner_13 = uispinner(app.GridLayout10);
            app.Spinner_13.Step = 20;
            app.Spinner_13.LowerLimitInclusive = 'off';
            app.Spinner_13.Limits = [0 5000];
            app.Spinner_13.Value = 1600;
            app.Spinner_14 = uispinner(app.GridLayout10);
            app.Spinner_14.Step = 0.25;
            app.Spinner_14.Limits = [0 5];
            app.Spinner_14.Value = 1;
            app.FrequencyHzLabel = uilabel(app.GridLayout10);
            app.FrequencyHzLabel.Text = 'Frequency(Hz)';
            app.EnergyLabel = uilabel(app.GridLayout10);
            app.EnergyLabel.Text = 'Energy';
            app.ModulatorPanel = uipanel(app.GridLayout9);
            app.ModulatorPanel.Title = 'Modulator';
            app.GridLayout10_2 = uigridlayout(app.ModulatorPanel);
            app.CheckBox_8 = uicheckbox(app.GridLayout10_2);
            app.CheckBox_8.Text = '';
            app.Spinner_15 = uispinner(app.GridLayout10_2);
            app.Spinner_15.Step = 20;
            app.Spinner_15.LowerLimitInclusive = 'off';
            app.Spinner_15.Limits = [0 5000];
            app.Spinner_15.Value = 10;
            app.Spinner_16 = uispinner(app.GridLayout10_2);
            app.Spinner_16.Step = 0.25;
            app.Spinner_16.Limits = [0 5];
            app.Spinner_16.Value = 1;
            app.CheckBox_9 = uicheckbox(app.GridLayout10_2);
            app.CheckBox_9.Text = '';
            app.Spinner_17 = uispinner(app.GridLayout10_2);
            app.Spinner_17.Step = 20;
            app.Spinner_17.LowerLimitInclusive = 'off';
            app.Spinner_17.Limits = [0 5000];
            app.Spinner_17.Value = 20;
            app.Spinner_18 = uispinner(app.GridLayout10_2);
            app.Spinner_18.Step = 0.25;
            app.Spinner_18.Limits = [0 5];
            app.Spinner_18.Value = 1;
            app.CheckBox_10 = uicheckbox(app.GridLayout10_2);
            app.CheckBox_10.Text = '';
            app.Spinner_19 = uispinner(app.GridLayout10_2);
            app.Spinner_19.Step = 20;
            app.Spinner_19.LowerLimitInclusive = 'off';
            app.Spinner_19.Limits = [0 5000];
            app.Spinner_19.Value = 40;
            app.Spinner_20 = uispinner(app.GridLayout10_2);
            app.Spinner_20.Step = 0.25;
            app.Spinner_20.Limits = [0 5];
            app.Spinner_20.Value = 1;
            app.CheckBox_11 = uicheckbox(app.GridLayout10_2);
            app.CheckBox_11.Text = '';
            app.Spinner_21 = uispinner(app.GridLayout10_2);
            app.Spinner_21.Step = 20;
            app.Spinner_21.LowerLimitInclusive = 'off';
            app.Spinner_21.Limits = [0 5000];
            app.Spinner_21.Value = 60;
            app.Spinner_22 = uispinner(app.GridLayout10_2);
            app.Spinner_22.Step = 0.25;
            app.Spinner_22.Limits = [0 5];
            app.Spinner_22.Value = 1;
            app.CheckBox_12 = uicheckbox(app.GridLayout10_2);
            app.CheckBox_12.Text = '';
            app.Spinner_23 = uispinner(app.GridLayout10_2);
            app.Spinner_23.Step = 20;
            app.Spinner_23.LowerLimitInclusive = 'off';
            app.Spinner_23.Limits = [0 5000];
            app.Spinner_23.Value = 80;
            app.Spinner_24 = uispinner(app.GridLayout10_2);
            app.Spinner_24.Step = 0.25;
            app.Spinner_24.Limits = [0 5];
            app.Spinner_24.Value = 1;
            app.CheckBox_13 = uicheckbox(app.GridLayout10_2);
            app.CheckBox_13.Text = '';
            app.Spinner_25 = uispinner(app.GridLayout10_2);
            app.Spinner_25.Step = 20;
            app.Spinner_25.LowerLimitInclusive = 'off';
            app.Spinner_25.Limits = [0 5000];
            app.Spinner_25.Value = 20;
            app.Spinner_26 = uispinner(app.GridLayout10_2);
            app.Spinner_26.Step = 0.25;
            app.Spinner_26.Limits = [0 5];
            app.Spinner_26.Value = 1;
            app.FrequencyHzLabel_2 = uilabel(app.GridLayout10_2);
            app.FrequencyHzLabel_2.Text = 'Frequency(Hz)';
            app.EnergyLabel_2 = uilabel(app.GridLayout10_2);
            app.EnergyLabel_2.Text = 'Energy';
            app.Panel_6 = uipanel(app.GridLayout9);
            app.Knob = uiknob(app.Panel_6, 'discrete');
            app.Knob.Items = {'Fixed Carrier', 'Normal', 'Fixed Modulation'};
            app.Knob.Value = 'Normal';
            app.CyclerPanel = uipanel(app.GridLayout9);
            app.CyclerPanel.Title = 'Cycler';
            app.GridLayout11 = uigridlayout(app.CyclerPanel);
            app.StartSpinnerLabel = uilabel(app.GridLayout11);
            app.StartSpinnerLabel.Text = 'Start';
            app.StartSpinner = uispinner(app.GridLayout11);
            app.StartSpinner.Value = 300;
            app.StepSpinnerLabel = uilabel(app.GridLayout11);
            app.StepSpinnerLabel.Text = 'Step';
            app.StepSpinner = uispinner(app.GridLayout11);
            app.StepSpinner.Value = 100;
            app.EndSpinnerLabel = uilabel(app.GridLayout11);
            app.EndSpinnerLabel.Text = 'End';
            app.EndSpinner = uispinner(app.GridLayout11);
            app.EndSpinner.Value = 800;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Panel_7 = uipanel(app.GridLayout);
            app.Panel_7.Title = 'Panel';
            app.GridLayout12 = uigridlayout(app.Panel_7);
            app.UIAxes = uiaxes(app.GridLayout12);
            title(app.UIAxes, 'West Carrier')
            xlabel(app.UIAxes, 'time (sec)')
            ylabel(app.UIAxes, 'Amplitude (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout12);
            title(app.UIAxes_2, 'West Modulator')
            xlabel(app.UIAxes_2, 'time (sec)')
            ylabel(app.UIAxes_2, 'Amplitude (V)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.GridLayout12);
            title(app.UIAxes_3, 'West Stimulus')
            xlabel(app.UIAxes_3, 'time (sec)')
            ylabel(app.UIAxes_3, 'Amplitude (V)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.GridLayout12);
            title(app.UIAxes_4, 'East Carrier')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.GridLayout12);
            title(app.UIAxes_5, 'East Modulator')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.GridLayout12);
            title(app.UIAxes_6, 'East Stimulus')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.EastattndBSlider = uislider(app.GridLayout12);
            app.EastattndBSlider.Limits = [-72 -6];
            app.EastattndBSlider.MajorTicks = [-72 -69 -66 -63 -60 -57 -54 -51 -48 -45 -42 -39 -36 -33 -30 -27 -24 -21 -18 -15 -12 -9 -6 -3 0];
            app.EastattndBSlider.Value = -27;
            app.EditField = uieditfield(app.GridLayout12, 'numeric');
            app.EditField.Value = -27;
            app.EditField_2 = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_2.Value = -27;
            app.WestattndBSlider = uislider(app.GridLayout12);
            app.WestattndBSlider.Limits = [-72 -6];
            app.WestattndBSlider.MajorTicks = [-72 -69 -66 -63 -60 -57 -54 -51 -48 -45 -42 -39 -36 -33 -30 -27 -24 -21 -18 -15 -12 -9 -6 -3 0];
            app.WestattndBSlider.Value = -27;
            app.WestattndBLabel = uilabel(app.GridLayout12);
            app.WestattndBLabel.Text = 'West (attn dB)';
            app.EastattndBLabel = uilabel(app.GridLayout12);
            app.EastattndBLabel.Text = 'East (attn dB)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimulusV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end